classdef FinalProjectGUI_basicMovement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LEDControlPanel     matlab.ui.container.Panel
        YellowLEDLamp       matlab.ui.control.Lamp
        YellowLEDLampLabel  matlab.ui.control.Label
        RedLEDLamp          matlab.ui.control.Lamp
        RedLEDLampLabel     matlab.ui.control.Label
        GreenLEDLamp        matlab.ui.control.Lamp
        GreenLEDLampLabel   matlab.ui.control.Label
        YellowSwitch        matlab.ui.control.Switch
        YellowSwitchLabel   matlab.ui.control.Label
        RedSwitch           matlab.ui.control.Switch
        RedSwitchLabel      matlab.ui.control.Label
        GreenSwitch         matlab.ui.control.Switch
        GreenSwitchLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            subscribe(app.mqClient, "ece445/parkj10/to_arduino", "Callback", @app.showSentMessage);
            subscribe(app.mqClient, "ece445/parkj10/to_matlab", "Callback", @app.showReceivedMessage);
            pause(0.5); % Allow connection to establish
        end

        % Value changed function: GreenSwitch
        function GreenSwitchValueChanged(app, event)
            value = app.GreenSwitch.Value;
            
        end

        % Value changed function: YellowSwitch
        function YellowSwitchValueChanged(app, event)
            value = app.YellowSwitch.Value;
            
        end

        % Value changed function: RedSwitch
        function RedSwitchValueChanged(app, event)
            value = app.RedSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDControlPanel = uipanel(app.UIFigure);
            app.LEDControlPanel.Title = 'LED Control Panel';
            app.GreenSwitchLabel = uilabel(app.LEDControlPanel);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.LEDControlPanel, 'slider');
            app.GreenSwitch.ValueChangedFcn = createCallbackFcn(app, @GreenSwitchValueChanged, true);
            app.RedSwitchLabel = uilabel(app.LEDControlPanel);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.LEDControlPanel, 'slider');
            app.RedSwitch.ValueChangedFcn = createCallbackFcn(app, @RedSwitchValueChanged, true);
            app.YellowSwitchLabel = uilabel(app.LEDControlPanel);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.LEDControlPanel, 'slider');
            app.YellowSwitch.ValueChangedFcn = createCallbackFcn(app, @YellowSwitchValueChanged, true);
            app.GreenLEDLampLabel = uilabel(app.LEDControlPanel);
            app.GreenLEDLampLabel.Text = 'Green LED';
            app.GreenLEDLamp = uilamp(app.LEDControlPanel);
            app.RedLEDLampLabel = uilabel(app.LEDControlPanel);
            app.RedLEDLampLabel.Text = 'Red LED';
            app.RedLEDLamp = uilamp(app.LEDControlPanel);
            app.YellowLEDLampLabel = uilabel(app.LEDControlPanel);
            app.YellowLEDLampLabel.Text = 'Yellow LED';
            app.YellowLEDLamp = uilamp(app.LEDControlPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectGUI_basicMovement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end